classdef MTracer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FileTab                         matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        InfoPanel                       matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        SaveSessionButton               matlab.ui.control.Button
        LoadSessionButton               matlab.ui.control.Button
        RecEditField                    matlab.ui.control.EditField
        RecordingNameLabel              matlab.ui.control.Label
        LoadDataPanel                   matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        ChanMapLamp                     matlab.ui.control.Lamp
        LoadChanMapButton               matlab.ui.control.Button
        ChannelMapEditFieldLabel        matlab.ui.control.Label
        ChanMapEditField                matlab.ui.control.EditField
        LoadApButton                    matlab.ui.control.Button
        LoadLfButton                    matlab.ui.control.Button
        LoadRezButton                   matlab.ui.control.Button
        ApLamp                          matlab.ui.control.Lamp
        LfLamp                          matlab.ui.control.Lamp
        RezLamp                         matlab.ui.control.Lamp
        lfbinEditFieldLabel             matlab.ui.control.Label
        LfEditField                     matlab.ui.control.EditField
        ApEditField                     matlab.ui.control.EditField
        apbinLabel                      matlab.ui.control.Label
        RezEditField                    matlab.ui.control.EditField
        rezmatEditFieldLabel            matlab.ui.control.Label
        MapsTab                         matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        GridLayout15                    matlab.ui.container.GridLayout
        RoiTable                        matlab.ui.control.Table
        GridLayout14                    matlab.ui.container.GridLayout
        FocusDepthEditField             matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        FocusTimeEditField              matlab.ui.control.NumericEditField
        FocussecumLabel                 matlab.ui.control.Label
        GotoButton                      matlab.ui.control.Button
        ForgetButton                    matlab.ui.control.Button
        RemeberButton                   matlab.ui.control.Button
        DepthWin2EditField              matlab.ui.control.NumericEditField
        toEditField_2Label              matlab.ui.control.Label
        DepthWin1EditField              matlab.ui.control.NumericEditField
        DepthWindowumEditFieldLabel     matlab.ui.control.Label
        TimeWin2EditField               matlab.ui.control.NumericEditField
        toEditFieldLabel                matlab.ui.control.Label
        TimeWin1EditField               matlab.ui.control.NumericEditField
        TimeWindowsecLabel              matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        SaveMapDataButton               matlab.ui.control.Button
        MapsWindowButton                matlab.ui.control.Button
        TracingTab                      matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        TracerDropDown                  matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        LoadTracerButton                matlab.ui.control.Button
        SaveTracerButton                matlab.ui.control.Button
        TracerNameEditField             matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        TestTracerButton                matlab.ui.control.Button
        TestFunEditField                matlab.ui.control.EditField
        TestFunEditFieldLabel           matlab.ui.control.Label
        TrainTracerButton               matlab.ui.control.Button
        TrainFuncEditField              matlab.ui.control.EditField
        TrainFuncEditFieldLabel         matlab.ui.control.Label
        AutoTracingLabel                matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        SegmentButton                   matlab.ui.control.Button
        BreakButton                     matlab.ui.control.Button
        MergeButton                     matlab.ui.control.Button
        DeleteTraceButton               matlab.ui.control.Button
        InspectTraceButton              matlab.ui.control.Button
        SaveTraceButton                 matlab.ui.control.Button
        LoadTraceButton                 matlab.ui.control.Button
        GridLayout4                     matlab.ui.container.GridLayout
        TraceListLabel                  matlab.ui.control.Label
        TraceListBox                    matlab.ui.control.ListBox
        CorrectionTab                   matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        ApplymotioncorrectiontodataPanel  matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        CorrectMapLamp                  matlab.ui.control.Lamp
        GridLayout18                    matlab.ui.container.GridLayout
        OutputdirectoryLabel            matlab.ui.control.Label
        OutDirEditField                 matlab.ui.control.EditField
        OutDirButton                    matlab.ui.control.Button
        ThisoperationcannotbeundoneReloadthedataifLabel  matlab.ui.control.Label
        CorrectBinButton                matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        CorrectMapButton                matlab.ui.control.Button
        CorrectbinariesLabel            matlab.ui.control.Label
        GridLayout17                    matlab.ui.container.GridLayout
        SelectwhichfilestocorrectLabel  matlab.ui.control.Label
        CorrectLfCheckBox               matlab.ui.control.CheckBox
        CorrectApCheckBox               matlab.ui.control.CheckBox
        Interp2Lamp                     matlab.ui.control.Lamp
        LoadInterp2Button               matlab.ui.control.Button
        Interp2EditField                matlab.ui.control.EditField
        LoadinterpolantLabel            matlab.ui.control.Label
        ShortcutsTab                    matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        PlotHelpLabel                   matlab.ui.control.Label
        NavigHelpLabel                  matlab.ui.control.Label
        TraceHelpLabel                  matlab.ui.control.Label
    end


    properties (Access = private)
        % View-model
        vm MTracerVM;

        % Colors for Lamp
        none = [0 0 0];
        loading = [0.93 0.69 0.13];
        loaded = [0.47 0.67 0.19];
    end

    methods
        function ind = SelectedTraceInd(app)
            value = app.TraceListBox.Value;
            ind = ismember(app.TraceListBox.Items, value);
            if ~any(ind)
                ind = NaN;
            else
                ind = find(ind);
            end
        end
        function SelectTrace(app, ind)
            if isnan(ind)
                app.TraceListBox.Value = {};
            else
                app.TraceListBox.Value = app.TraceListBox.Items(ind);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize trace listbox
            app.TraceListBox.Items = {};

            % Initialize ROI table
            app.RoiTable.ColumnName = {'t1 (s)', 't2 (s)', 'y1 (um)', 'y2 (um)'};
            app.RoiTable.ColumnWidth = {'1x', '1x', '1x', '1x'};
            app.RoiTable.SelectionType = 'row';
            app.RoiTable.Multiselect = 'off';

            % Initialize view-model
            app.vm = MTracerVM(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.vm);
            delete(app);
        end

        % Button pushed function: LoadRezButton
        function LoadRezButtonPushed(app, event)
            % Prompt selection if there is no user input or spike data exist
            app.LoadRezButton.Enable = 'off';
            filePath = app.RezEditField.Value;
            if isempty(filePath)
                filePath = MBrowse.File([], 'Please select a rez.mat file', '*.mat');
            end

            if ~isempty(filePath)
                % Change UI status during loading
                app.RezLamp.Color = [0.93 0.69 0.13];
                pause(0.05);

                % Load data
                app.vm.LoadKilosortRez(filePath);

                if app.vm.hasSpikes
                    app.RezEditField.Value = filePath;
                    app.RezLamp.Color = [0.47 0.67 0.19];
                    app.vm.PlotSpikes();
                else
                    uialert(app.UIFigure, 'Unable to find or load rez.mat', 'Hmmm...', 'Icon', 'warning')
                    app.RezLamp.Color = [0 0 0];
                end
            end
            app.LoadRezButton.Enable = 'on';
        end

        % Button pushed function: LoadChanMapButton
        function LoadChanMapButtonPushed(app, event)
            % Prompt selection if there is no user input or spike data exist
            app.LoadChanMapButton.Enable = 'off';
            filePath = app.ChanMapEditField.Value;
            if isempty(filePath)
                filePath = MBrowse.File([], 'Please select a channel map file', '*.mat');
            end

            if ~isempty(filePath)
                % Change UI status during loading
                app.ChanMapLamp.Color = [0.93 0.69 0.13];
                pause(0.05);

                % Load data
                app.vm.LoadChannelMap(filePath);

                if app.vm.hasChanMap
                    app.ChanMapEditField.Value = filePath;
                    app.ChanMapLamp.Color = [0.47 0.67 0.19];
                else
                    uialert(app.UIFigure, 'Unable to find or load the channel map', 'Hmmm...', 'Icon', 'warning')
                    app.ChanMapLamp.Color = [0 0 0];
                end
            end
            app.LoadChanMapButton.Enable = 'on';
        end

        % Button pushed function: LoadLfButton
        function LoadLfButtonPushed(app, event)
            % Check channal map
            if ~app.vm.hasChanMap
                uialert(app.UIFigure, 'Channel Map must be loaded before loading lf.bin or ap.bin', 'Oops...', 'Icon', 'warning');
                return
            end

            % Prompt selection if there is no user input or spike data exist
            app.LoadLfButton.Enable = 'off';
            filePath = app.LfEditField.Value;
            if isempty(filePath)
                filePath = MBrowse.File([], 'Please select a lf.bin file', '*.bin');
            end

            if ~isempty(filePath)
                % Change UI status during loading
                app.LfLamp.Color = [0.93 0.69 0.13];
                pause(0.05);

                % Load data
                app.vm.LoadLFP(filePath);

                if app.vm.hasLFP
                    app.LfEditField.Value = filePath;
                    app.LfLamp.Color = [0.47 0.67 0.19];
                    app.vm.PlotLFP();
                else
                    uialert(app.UIFigure, 'Unable to find or load lf.bin', 'Hmmm...', 'Icon', 'warning')
                    app.LfLamp.Color = [0 0 0];
                end
            end
            app.LoadLfButton.Enable = 'on';
        end

        % Button pushed function: LoadApButton
        function LoadApButtonPushed(app, event)
            % Check channal map
            if ~app.vm.hasChanMap
                uialert(app.UIFigure, 'Channel Map must be loaded before loading lf.bin or ap.bin', 'Oops...', 'Icon', 'warning');
                return
            end

            % Prompt selection if there is no user input or spike data exist
            app.LoadApButton.Enable = 'off';
            filePath = app.ApEditField.Value;
            if isempty(filePath)
                filePath = MBrowse.File([], 'Please select a ap.bin file', '*.bin');
            end

            if ~isempty(filePath)
                % Change UI status during loading
                app.ApLamp.Color = [0.93 0.69 0.13];
                pause(0.05);

                % Load data
                app.vm.LoadAP(filePath);

                if app.vm.hasAP
                    app.ApEditField.Value = filePath;
                    app.ApLamp.Color = [0.47 0.67 0.19];
                    app.vm.PlotLFP();
                else
                    uialert(app.UIFigure, 'Unable to find or load ap.bin', 'Hmmm...', 'Icon', 'warning')
                    app.ApLamp.Color = [0 0 0];
                end
            end
            app.LoadApButton.Enable = 'on';
        end

        % Value changed function: RecEditField
        function RecEditFieldValueChanged(app, event)
            value = app.RecEditField.Value;
            if ~isempty(value)
                app.vm.recId = value;
            else
                app.RecEditField.Value = app.vm.recId;
            end
        end

        % Button pushed function: SaveSessionButton
        function SaveSessionButtonPushed(app, event)
            mt = app.vm.Duplicate();
            mt.appData.RoiTable = app.RoiTable.Data;
            defName = ['MTracer_session_' mt.recId '_' datestr(now, 'yyyy-mm-dd_HH-MM-SS')];
            [fileName, dirPath] = uiputfile('.mat', 'Specify a directory and file name to save the current session', defName);
            if fileName
                save(fullfile(dirPath, fileName), 'mt');
            end
        end

        % Button pushed function: LoadSessionButton
        function LoadSessionButtonPushed(app, event)
            filePath = MBrowse.File([], '.mat');
            if isempty(filePath)
                return
            end
            load(filePath, 'mt');
            mt.app = app;
            delete(app.vm);
            app.vm = mt;

            % Update UI controls in File tab
            app.RezEditField.Value = mt.rezFile;
            app.ChanMapEditField.Value = mt.chanMapFile;
            app.LfEditField.Value = mt.lfBinFile;
            app.ApEditField.Value = mt.apBinFile;
            if mt.hasSpikes
                app.RezLamp.Color = [0.47 0.67 0.19];
            end
            if mt.hasChanMap
                app.ChanMapLamp.Color = [0.47 0.67 0.19];
            end
            if mt.hasLFP
                app.LfLamp.Color = [0.47 0.67 0.19];
            end
            if mt.hasAP
                app.ApLamp.Color = [0.47 0.67 0.19];
            end
            app.RecEditField.Value = mt.recId;

            % Update UI controls in Maps tab
            app.RoiTable.Data = mt.appData.RoiTable;

            % Update UI controls in Tracing tab
            mt.PlotTraces();

            % Update UI controls in Correction tab
            app.Interp2EditField.Value = mt.F2File;
            if mt.hasInterp2
                app.Interp2Lamp.Color = [0.47 0.67 0.19];
            end
            app.CorrectMapLamp.Color = [0 0 0];
        end

        % Button pushed function: MapsWindowButton
        function MapsWindowButtonPushed(app, event)
            app.vm.PlotAll();
        end

        % Button pushed function: SaveMapDataButton
        function SaveMapDataButtonPushed(app, event)
            if ~app.vm.hasMapAxes
                uialert(app.UIFigure, 'The map must be present to specify the region of interest', 'Oops...', 'Icon', 'warning');
                return
            end
            s = app.vm.SliceMap();
            assignin('base', 'md', s);
            disp('Map data saved to workspace in a variable named ''md''');
            disp(s);
        end

        % Value changed function: DepthWin1EditField, 
        % DepthWin2EditField, TimeWin1EditField, TimeWin2EditField
        function TimeWin1EditFieldValueChanged(app, event)
            tWin = [app.TimeWin1EditField.Value, app.TimeWin2EditField.Value];
            yWin = [app.DepthWin1EditField.Value, app.DepthWin2EditField.Value];
            app.vm.UpdateMapLims(tWin, yWin);
        end

        % Button pushed function: RemeberButton
        function RemeberButtonPushed(app, event)
            app.RoiTable.Data = [ ...
                app.TimeWin1EditField.Value, app.TimeWin2EditField.Value, app.DepthWin1EditField.Value, app.DepthWin2EditField.Value; ...
                app.RoiTable.Data];
        end

        % Button pushed function: ForgetButton
        function ForgetButtonPushed(app, event)
            idx = app.RoiTable.Selection;
            if ~isempty(idx) && idx <= size(app.RoiTable.Data, 1)
                app.RoiTable.Data(idx,:) = [];
            end
        end

        % Button pushed function: GotoButton
        function GotoButtonPushed(app, event)
            idx = app.RoiTable.Selection;
            if ~isempty(idx) && idx <= size(app.RoiTable.Data, 1)
                tWin = app.RoiTable.Data(idx, 1:2);
                yWin = app.RoiTable.Data(idx, 3:4);
                app.vm.UpdateMapLims(tWin, yWin);
            end
        end

        % Button pushed function: LoadTraceButton
        function LoadTraceButtonPushed(app, event)
            app.vm.LoadTraces();
        end

        % Button pushed function: SaveTraceButton
        function SaveTraceButtonPushed(app, event)
            app.vm.SaveTraces();
        end

        % Value changed function: TraceListBox
        function TraceListBoxValueChanged(app, event)
            app.vm.currentTrace = app.SelectedTraceInd();
            app.vm.PlotTraces();
        end

        % Button pushed function: DeleteTraceButton
        function DeleteTraceButtonPushed(app, event)
            app.vm.DeleteTrace(app.SelectedTraceInd());
        end

        % Button pushed function: InspectTraceButton
        function InspectTraceButtonPushed(app, event)
            ind = app.vm.currentTrace;
            if ~isnan(ind)
                for i = ind(:)'
                    app.vm.traces(i).PlotMotions();
                end
            end
        end

        % Button pushed function: LoadInterp2Button
        function LoadInterp2ButtonPushed(app, event)
            % Prompt selection if there is no user input or spike data exist
            app.LoadInterp2Button.Enable = 'off';
            filePath = app.Interp2EditField.Value;
            if isempty(filePath)
                filePath = MBrowse.File([], 'Please select an interpolant file', '*.mat');
            end

            if ~isempty(filePath)
                % Change UI status during loading
                app.Interp2Lamp.Color = [0.93 0.69 0.13];
                pause(0.05);

                % Load data
                try
                    load(filePath, 'F2');
                    app.vm.F2 = F2;
                    app.vm.F2File = filePath;
                catch
                    uialert(app.UIFigure, 'Unable to find or load the interpolant file', 'Hmmm...', 'Icon', 'warning')
                    app.Interp2Lamp.Color = [0 0 0];
                end

                if app.vm.hasInterp2
                    app.Interp2EditField.Value = filePath;
                    app.Interp2Lamp.Color = [0.47 0.67 0.19];
                    app.CorrectMapButton.Enable = true;
                end
            end
            app.LoadInterp2Button.Enable = 'on';
        end

        % Button pushed function: CorrectMapButton
        function CorrectMapButtonPushed(app, event)
            app.CorrectMapButton.Enable = 'off';
            app.CorrectMapLamp.Color = [0.93 0.69 0.13];
            pause(0.05);
            try
                app.vm.ApplyCorrection();
            catch ME
                app.CorrectMapButton.Enable = 'on';
                app.CorrectMapLamp.Color = [0 0 0];
                rethrow(ME);
            end
            app.CorrectMapButton.Enable = 'on';
            app.CorrectMapLamp.Color = [0.47 0.67 0.19];
        end

        % Button pushed function: OutDirButton
        function OutDirButtonPushed(app, event)
            dirPath = uigetdir();
            if dirPath
                app.OutDirEditField.Value = dirPath;
            end
        end

        % Button pushed function: CorrectBinButton
        function CorrectBinButtonPushed(app, event)
            if ~app.vm.hasInterp2 || ~app.vm.hasAP
                uialert(app.UIFigure, 'Correction requires both an interpolant and ap.bin data loaded', 'Oops...', 'Icon', 'info')
                return
            end
            srcList = {'ap', 'lf'};
            srcList = srcList([app.CorrectApCheckBox.Value app.CorrectLfCheckBox.Value]);
            if isempty(srcList)
                uialert(app.UIFigure, 'You did not select anything to run', 'Hmmm...', 'Icon', 'info')
                return
            end
            app.vm.F2.CorrectBinary(app.vm.imec, app.OutDirEditField.Value, srcList);
        end

        % Value changed function: FocusDepthEditField, 
        % FocusTimeEditField
        function FocusTimeEditFieldValueChanged(app, event)
            t = MMath.Bound(app.FocusTimeEditField.Value, app.vm.tLims);
            y = MMath.Bound(app.FocusDepthEditField.Value, app.vm.yLims);
            app.vm.focus = [t y];
            app.vm.PlotFocus();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MTracer';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileTab = uitab(app.TabGroup);
            app.FileTab.Title = 'File';
            app.GridLayout9 = uigridlayout(app.FileTab);
            app.LoadDataPanel = uipanel(app.GridLayout9);
            app.LoadDataPanel.Title = 'Load Data';
            app.GridLayout = uigridlayout(app.LoadDataPanel);
            app.rezmatEditFieldLabel = uilabel(app.GridLayout);
            app.rezmatEditFieldLabel.Text = 'rez.mat';
            app.RezEditField = uieditfield(app.GridLayout, 'text');
            app.apbinLabel = uilabel(app.GridLayout);
            app.apbinLabel.Text = 'ap.bin';
            app.ApEditField = uieditfield(app.GridLayout, 'text');
            app.LfEditField = uieditfield(app.GridLayout, 'text');
            app.lfbinEditFieldLabel = uilabel(app.GridLayout);
            app.lfbinEditFieldLabel.Text = 'lf.bin';
            app.RezLamp = uilamp(app.GridLayout);
            app.LfLamp = uilamp(app.GridLayout);
            app.ApLamp = uilamp(app.GridLayout);
            app.LoadRezButton = uibutton(app.GridLayout, 'push');
            app.LoadRezButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRezButtonPushed, true);
            app.LoadRezButton.Text = 'Load';
            app.LoadLfButton = uibutton(app.GridLayout, 'push');
            app.LoadLfButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLfButtonPushed, true);
            app.LoadLfButton.Text = 'Load';
            app.LoadApButton = uibutton(app.GridLayout, 'push');
            app.LoadApButton.ButtonPushedFcn = createCallbackFcn(app, @LoadApButtonPushed, true);
            app.LoadApButton.Text = 'Load';
            app.ChanMapEditField = uieditfield(app.GridLayout, 'text');
            app.ChanMapEditField.Value = 'NP1_NHP_HalfCol_kilosortChanMap.mat';
            app.ChannelMapEditFieldLabel = uilabel(app.GridLayout);
            app.ChannelMapEditFieldLabel.Text = 'Channel Map';
            app.LoadChanMapButton = uibutton(app.GridLayout, 'push');
            app.LoadChanMapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadChanMapButtonPushed, true);
            app.LoadChanMapButton.Text = 'Load';
            app.ChanMapLamp = uilamp(app.GridLayout);
            app.InfoPanel = uipanel(app.GridLayout9);
            app.InfoPanel.Title = 'Info';
            app.GridLayout10 = uigridlayout(app.InfoPanel);
            app.RecordingNameLabel = uilabel(app.GridLayout10);
            app.RecordingNameLabel.Text = 'Recording Name';
            app.RecEditField = uieditfield(app.GridLayout10, 'text');
            app.RecEditField.ValueChangedFcn = createCallbackFcn(app, @RecEditFieldValueChanged, true);
            app.RecEditField.Value = 'NP0_B0';
            app.LoadSessionButton = uibutton(app.GridLayout10, 'push');
            app.LoadSessionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSessionButtonPushed, true);
            app.LoadSessionButton.Text = 'Load Session';
            app.SaveSessionButton = uibutton(app.GridLayout10, 'push');
            app.SaveSessionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSessionButtonPushed, true);
            app.SaveSessionButton.Text = 'Save Session';
            app.MapsTab = uitab(app.TabGroup);
            app.MapsTab.Title = 'Maps';
            app.GridLayout11 = uigridlayout(app.MapsTab);
            app.GridLayout6 = uigridlayout(app.GridLayout11);
            app.MapsWindowButton = uibutton(app.GridLayout6, 'push');
            app.MapsWindowButton.ButtonPushedFcn = createCallbackFcn(app, @MapsWindowButtonPushed, true);
            app.MapsWindowButton.Text = 'Maps Window';
            app.SaveMapDataButton = uibutton(app.GridLayout6, 'push');
            app.SaveMapDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMapDataButtonPushed, true);
            app.SaveMapDataButton.Text = {'Save Map Data'; 'to Workspace'};
            app.GridLayout14 = uigridlayout(app.GridLayout11);
            app.TimeWindowsecLabel = uilabel(app.GridLayout14);
            app.TimeWindowsecLabel.Text = 'Time Window (sec) :';
            app.TimeWin1EditField = uieditfield(app.GridLayout14, 'numeric');
            app.TimeWin1EditField.Limits = [0 Inf];
            app.TimeWin1EditField.ValueChangedFcn = createCallbackFcn(app, @TimeWin1EditFieldValueChanged, true);
            app.toEditFieldLabel = uilabel(app.GridLayout14);
            app.toEditFieldLabel.Text = 'to';
            app.TimeWin2EditField = uieditfield(app.GridLayout14, 'numeric');
            app.TimeWin2EditField.Limits = [0 Inf];
            app.TimeWin2EditField.ValueChangedFcn = createCallbackFcn(app, @TimeWin1EditFieldValueChanged, true);
            app.TimeWin2EditField.Value = 10000;
            app.DepthWindowumEditFieldLabel = uilabel(app.GridLayout14);
            app.DepthWindowumEditFieldLabel.Text = 'Depth Window (um) :';
            app.DepthWin1EditField = uieditfield(app.GridLayout14, 'numeric');
            app.DepthWin1EditField.Limits = [0 Inf];
            app.DepthWin1EditField.ValueChangedFcn = createCallbackFcn(app, @TimeWin1EditFieldValueChanged, true);
            app.toEditField_2Label = uilabel(app.GridLayout14);
            app.toEditField_2Label.Text = 'to';
            app.DepthWin2EditField = uieditfield(app.GridLayout14, 'numeric');
            app.DepthWin2EditField.Limits = [0 Inf];
            app.DepthWin2EditField.ValueChangedFcn = createCallbackFcn(app, @TimeWin1EditFieldValueChanged, true);
            app.DepthWin2EditField.Value = 7660;
            app.RemeberButton = uibutton(app.GridLayout14, 'push');
            app.RemeberButton.ButtonPushedFcn = createCallbackFcn(app, @RemeberButtonPushed, true);
            app.RemeberButton.Text = 'Remeber';
            app.ForgetButton = uibutton(app.GridLayout14, 'push');
            app.ForgetButton.ButtonPushedFcn = createCallbackFcn(app, @ForgetButtonPushed, true);
            app.ForgetButton.Text = 'Forget';
            app.GotoButton = uibutton(app.GridLayout14, 'push');
            app.GotoButton.ButtonPushedFcn = createCallbackFcn(app, @GotoButtonPushed, true);
            app.GotoButton.Text = 'Go to';
            app.FocussecumLabel = uilabel(app.GridLayout14);
            app.FocussecumLabel.Text = 'Focus (sec, um) :';
            app.FocusTimeEditField = uieditfield(app.GridLayout14, 'numeric');
            app.FocusTimeEditField.Limits = [0 Inf];
            app.FocusTimeEditField.ValueChangedFcn = createCallbackFcn(app, @FocusTimeEditFieldValueChanged, true);
            app.Label_2 = uilabel(app.GridLayout14);
            app.Label_2.Text = ',';
            app.FocusDepthEditField = uieditfield(app.GridLayout14, 'numeric');
            app.FocusDepthEditField.Limits = [0 Inf];
            app.FocusDepthEditField.ValueChangedFcn = createCallbackFcn(app, @FocusTimeEditFieldValueChanged, true);
            app.GridLayout15 = uigridlayout(app.GridLayout11);
            app.RoiTable = uitable(app.GridLayout15);
            app.RoiTable.ColumnName = {'t1 (s)'; 't2 (s)'; 'y1 (um)'; 'y2 (um)'};
            app.RoiTable.RowName = {};
            app.RoiTable.ColumnSortable = true;
            app.TracingTab = uitab(app.TabGroup);
            app.TracingTab.Title = 'Tracing';
            app.GridLayout3 = uigridlayout(app.TracingTab);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.TraceListBox = uilistbox(app.GridLayout4);
            app.TraceListBox.Multiselect = 'on';
            app.TraceListBox.ValueChangedFcn = createCallbackFcn(app, @TraceListBoxValueChanged, true);
            app.TraceListBox.Value = {};
            app.TraceListLabel = uilabel(app.GridLayout4);
            app.TraceListLabel.Text = 'Trace List';
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.LoadTraceButton = uibutton(app.GridLayout7, 'push');
            app.LoadTraceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTraceButtonPushed, true);
            app.LoadTraceButton.Text = 'Load';
            app.SaveTraceButton = uibutton(app.GridLayout7, 'push');
            app.SaveTraceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTraceButtonPushed, true);
            app.SaveTraceButton.Text = 'Save';
            app.InspectTraceButton = uibutton(app.GridLayout7, 'push');
            app.InspectTraceButton.ButtonPushedFcn = createCallbackFcn(app, @InspectTraceButtonPushed, true);
            app.InspectTraceButton.Text = 'Inspect';
            app.DeleteTraceButton = uibutton(app.GridLayout7, 'push');
            app.DeleteTraceButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTraceButtonPushed, true);
            app.DeleteTraceButton.Text = 'Delete';
            app.MergeButton = uibutton(app.GridLayout7, 'push');
            app.MergeButton.Text = 'Merge';
            app.BreakButton = uibutton(app.GridLayout7, 'push');
            app.BreakButton.Text = 'Break';
            app.SegmentButton = uibutton(app.GridLayout7, 'push');
            app.SegmentButton.Text = 'Segment';
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.AutoTracingLabel = uilabel(app.GridLayout8);
            app.AutoTracingLabel.Text = 'Auto Tracing';
            app.TrainFuncEditFieldLabel = uilabel(app.GridLayout8);
            app.TrainFuncEditFieldLabel.Text = 'Train Func';
            app.TrainFuncEditField = uieditfield(app.GridLayout8, 'text');
            app.TrainTracerButton = uibutton(app.GridLayout8, 'push');
            app.TrainTracerButton.Text = 'Train';
            app.TestFunEditFieldLabel = uilabel(app.GridLayout8);
            app.TestFunEditFieldLabel.Text = 'Test Fun';
            app.TestFunEditField = uieditfield(app.GridLayout8, 'text');
            app.TestTracerButton = uibutton(app.GridLayout8, 'push');
            app.TestTracerButton.Text = 'Test';
            app.NameEditFieldLabel = uilabel(app.GridLayout8);
            app.NameEditFieldLabel.Text = 'Name';
            app.TracerNameEditField = uieditfield(app.GridLayout8, 'text');
            app.SaveTracerButton = uibutton(app.GridLayout8, 'push');
            app.SaveTracerButton.Text = 'Save';
            app.LoadTracerButton = uibutton(app.GridLayout8, 'push');
            app.LoadTracerButton.Text = 'Load';
            app.DropDownLabel = uilabel(app.GridLayout8);
            app.DropDownLabel.Text = '';
            app.TracerDropDown = uidropdown(app.GridLayout8);
            app.TracerDropDown.Items = {};
            app.TracerDropDown.Value = {};
            app.CorrectionTab = uitab(app.TabGroup);
            app.CorrectionTab.Title = 'Correction';
            app.GridLayout13 = uigridlayout(app.CorrectionTab);
            app.ApplymotioncorrectiontodataPanel = uipanel(app.GridLayout13);
            app.ApplymotioncorrectiontodataPanel.Title = 'Apply motion correction to data';
            app.GridLayout16 = uigridlayout(app.ApplymotioncorrectiontodataPanel);
            app.LoadinterpolantLabel = uilabel(app.GridLayout16);
            app.LoadinterpolantLabel.Text = '1) Load interpolant :';
            app.Interp2EditField = uieditfield(app.GridLayout16, 'text');
            app.LoadInterp2Button = uibutton(app.GridLayout16, 'push');
            app.LoadInterp2Button.ButtonPushedFcn = createCallbackFcn(app, @LoadInterp2ButtonPushed, true);
            app.LoadInterp2Button.Text = 'Load';
            app.Interp2Lamp = uilamp(app.GridLayout16);
            app.GridLayout17 = uigridlayout(app.GridLayout16);
            app.CorrectApCheckBox = uicheckbox(app.GridLayout17);
            app.CorrectApCheckBox.Text = 'ap.bin';
            app.CorrectApCheckBox.Value = true;
            app.CorrectLfCheckBox = uicheckbox(app.GridLayout17);
            app.CorrectLfCheckBox.Text = 'lf.bin';
            app.CorrectLfCheckBox.Value = true;
            app.SelectwhichfilestocorrectLabel = uilabel(app.GridLayout17);
            app.SelectwhichfilestocorrectLabel.Text = 'Select which file(s) to correct :';
            app.CorrectbinariesLabel = uilabel(app.GridLayout16);
            app.CorrectbinariesLabel.Text = '3) Correct binaries :';
            app.CorrectMapButton = uibutton(app.GridLayout16, 'push');
            app.CorrectMapButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectMapButtonPushed, true);
            app.CorrectMapButton.Text = 'Correct';
            app.Label = uilabel(app.GridLayout16);
            app.Label.Text = '2) Test with map data :';
            app.CorrectBinButton = uibutton(app.GridLayout16, 'push');
            app.CorrectBinButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectBinButtonPushed, true);
            app.CorrectBinButton.Text = 'Correct';
            app.ThisoperationcannotbeundoneReloadthedataifLabel = uilabel(app.GridLayout16);
            app.ThisoperationcannotbeundoneReloadthedataifLabel.Text = 'This operation cannot be undone. Recommend to save the session first.';
            app.OutDirButton = uibutton(app.GridLayout16, 'push');
            app.OutDirButton.ButtonPushedFcn = createCallbackFcn(app, @OutDirButtonPushed, true);
            app.OutDirButton.Text = 'Browse';
            app.GridLayout18 = uigridlayout(app.GridLayout16);
            app.OutDirEditField = uieditfield(app.GridLayout18, 'text');
            app.OutDirEditField.Value = '(this folder must not have any file containing the recording id in file name)';
            app.OutputdirectoryLabel = uilabel(app.GridLayout18);
            app.OutputdirectoryLabel.Text = 'Output directory :';
            app.CorrectMapLamp = uilamp(app.GridLayout16);
            app.ShortcutsTab = uitab(app.TabGroup);
            app.ShortcutsTab.Title = 'Shortcuts';
            app.GridLayout2 = uigridlayout(app.ShortcutsTab);
            app.TraceHelpLabel = uilabel(app.GridLayout2);
            app.TraceHelpLabel.Text = {'Plot Control'; ''; '* 1 : toggle focus indicator'; '* 2 : toggle spikes'; '* 3 : toggle LFP heatmap'; '* 4 : toggle AP signals'; '* 5 : toggle anchor points'; '* 6 : toggle interpolated traces'; '* 7-9 : toggle user defined layers'; '* . : increase spike marker size'; '* , : decrease spike marker size'; '* v : plot AP signals aound focus'};
            app.NavigHelpLabel = uilabel(app.GridLayout2);
            app.NavigHelpLabel.Text = {'Navigation Control'; ''; '* Click on map: set focus'; '* ] : zoom in time'; '* [ : zoom out time'; '* + : zoom in depth'; '* - : zoom out depth'; '* o : view the whole map'; '* c : center focus'; '* w/s/a/d : move up/down/left/right'; '* Shift + w/s/a/d : move faster'; '* Scroll : move left/right'};
            app.PlotHelpLabel = uilabel(app.GridLayout2);
            app.PlotHelpLabel.Text = {'Tracing Control'; ''; '* t : enter tracing mode'; '* esc : exit tracing mode'; '* Left click : add an anchor point'; '* Right click : undo selected point'; '* Ctrl + z : undo last point'; '* Ctrl + Shift + z : redo'; '* q : query the next point with auto-tracer'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MTracer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end